classdef discreteGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DiscreteGUI                    matlab.ui.Figure
        ConverttoDiscreteLabel         matlab.ui.control.Label
        ParametersPanel                matlab.ui.container.Panel
        EnterButton                    matlab.ui.control.Button
        TimesamplingsecLabel           matlab.ui.control.Label
        ts                             matlab.ui.control.EditField
        ts_inc                         matlab.ui.control.Button
        ts_dec                         matlab.ui.control.Button
        ReadyLampLabel                 matlab.ui.control.Label
        ReadyLamp                      matlab.ui.control.Lamp
        AntiAliasingFilterPanel        matlab.ui.container.Panel
        LoadFilterFromWSButton         matlab.ui.control.Button
        FilternameintheworkspaceLabel  matlab.ui.control.Label
        AAF_string                     matlab.ui.control.EditField
        ReadyLamp_2Label               matlab.ui.control.Label
        ReadyLamp_2                    matlab.ui.control.Lamp
        OptionsPanel                   matlab.ui.container.Panel
        CancelButton                   matlab.ui.control.Button
        SaveExitButton                 matlab.ui.control.Button
        HelpButton                     matlab.ui.control.Button
        AssignnametothecontrollerEditFieldLabel  matlab.ui.control.Label
        Name                           matlab.ui.control.EditField
    end

    
    properties (Access = private)
        CallingApp;
        G
        des
        Ts
        AAF
        AAF_flag
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, G, des)
           app.CallingApp = mainapp;
           app.G = G;
           app.des = des;
        end

        % Button pushed function: ts_inc
        function ts_incButtonPushed(app, event)
            app.ts.Value = num2str(0.01 + str2double(app.ts.Value));
        end

        % Button pushed function: ts_dec
        function ts_decButtonPushed(app, event)
            app.ts.Value =num2str(0.8*str2double(app.ts.Value));
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            app.Ts = str2double(app.ts.Value);
            app.AAF_flag = 0;
            app.ReadyLamp.Color = [0,1,0];
        end

        % Button pushed function: LoadFilterFromWSButton
        function LoadFilterFromWSButtonPushed(app, event)
            app.AAF_flag = 1;
            aaf_string = app.AAF_string.Value;
            app.AAF = evalin("base",aaf_string);
            fprintf("You load %s - anti aliasing filter",aaf_string)
            app.ReadyLamp.Color = [0,1,0];
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            helpGUI('c2d')
        end

        % Button pushed function: SaveExitButton
        function SaveExitButtonPushed(app, event)
            name = app.Name.Value;
            if app.AAF_flag == 1
                updateC2D(app.CallingApp,app.G,app.des,app.Ts, name,app.AAF)
            else
                updateC2D(app.CallingApp,app.G,app.des,app.Ts, name) 
            end
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DiscreteGUI = uifigure('Visible', 'off');
            app.DiscreteGUI.Name = 'UI Figure';
            app.ConverttoDiscreteLabel = uilabel(app.DiscreteGUI);
            app.ConverttoDiscreteLabel.Text = 'Convert to Discrete';
            app.ParametersPanel = uipanel(app.DiscreteGUI);
            app.ParametersPanel.Title = 'Parameters';
            app.EnterButton = uibutton(app.ParametersPanel, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.TimesamplingsecLabel = uilabel(app.ParametersPanel);
            app.TimesamplingsecLabel.Text = 'Time sampling [sec]:';
            app.ts = uieditfield(app.ParametersPanel, 'text');
            app.ts.Value = '0.1';
            app.ts_inc = uibutton(app.ParametersPanel, 'push');
            app.ts_inc.ButtonPushedFcn = createCallbackFcn(app, @ts_incButtonPushed, true);
            app.ts_inc.Text = '+';
            app.ts_dec = uibutton(app.ParametersPanel, 'push');
            app.ts_dec.ButtonPushedFcn = createCallbackFcn(app, @ts_decButtonPushed, true);
            app.ts_dec.Text = '-';
            app.ReadyLampLabel = uilabel(app.ParametersPanel);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.ParametersPanel);
            app.AntiAliasingFilterPanel = uipanel(app.DiscreteGUI);
            app.AntiAliasingFilterPanel.Title = 'Anti - Aliasing Filter';
            app.LoadFilterFromWSButton = uibutton(app.AntiAliasingFilterPanel, 'push');
            app.LoadFilterFromWSButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFilterFromWSButtonPushed, true);
            app.LoadFilterFromWSButton.Text = 'Load Filter From W.S';
            app.FilternameintheworkspaceLabel = uilabel(app.AntiAliasingFilterPanel);
            app.FilternameintheworkspaceLabel.Text = 'Filter name in the workspace:';
            app.AAF_string = uieditfield(app.AntiAliasingFilterPanel, 'text');
            app.ReadyLamp_2Label = uilabel(app.AntiAliasingFilterPanel);
            app.ReadyLamp_2Label.Text = 'Ready';
            app.ReadyLamp_2 = uilamp(app.AntiAliasingFilterPanel);
            app.OptionsPanel = uipanel(app.DiscreteGUI);
            app.OptionsPanel.Title = 'Options';
            app.CancelButton = uibutton(app.OptionsPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SaveExitButton = uibutton(app.OptionsPanel, 'push');
            app.SaveExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveExitButtonPushed, true);
            app.SaveExitButton.Text = 'Save & Exit';
            app.HelpButton = uibutton(app.OptionsPanel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.AssignnametothecontrollerEditFieldLabel = uilabel(app.OptionsPanel);
            app.AssignnametothecontrollerEditFieldLabel.Text = 'Assign name to the controller:';
            app.Name = uieditfield(app.OptionsPanel, 'text');
            % Show the figure after all components are created
            app.DiscreteGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = discreteGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DiscreteGUI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DiscreteGUI)
        end
    end
end