classdef helpGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        HTML      matlab.ui.control.HTML
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, type)
            % get the path of the openQsyn folder
            html_path = what('openQsyn');
            %switch between GUI cases
            switch type
                case 'gain'
                    app.HTML.HTMLSource = fullfile(html_path.path,'/loopShapingGUI/html','doc_Gain.html');
                case 'lead'
                    app.HTML.HTMLSource = fullfile(html_path.path,'/loopShapingGUI/html','doc_Lead.html');
                case 'lag'
                    app.HTML.HTMLSource = fullfile(html_path.path,'/loopShapingGUI/html','doc_Lag.html');
                case 'pid'
                    app.HTML.HTMLSource = fullfile(html_path.path,'/loopShapingGUI/html','doc_PID.html');
                case 'pz'
                    app.HTML.HTMLSource = fullfile(html_path.path,'/loopShapingGUI/html','doc_Add_poles_zeros.html');
                case 'notch'
                    app.HTML.HTMLSource = fullfile(html_path.path,'/loopShapingGUI/html','doc_Notch_Anti_notch.html');
                case 'main'
                    app.HTML.HTMLSource = fullfile(html_path.path,'/loopShapingGUI/html','doc_Main_screen.html');
                case 'c2d'
                    app.HTML.HTMLSource = fullfile(html_path.path,'/loopShapingGUI/html','doc_c2d.html');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.HTML = uihtml(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = helpGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end