classdef lagGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Title                          matlab.ui.control.Label
        ParametersPanel                matlab.ui.container.Panel
        FrequencyradsecEditFieldLabel  matlab.ui.control.Label
        Freq                           matlab.ui.control.EditField
        BetaEditFieldLabel             matlab.ui.control.Label
        Beta                           matlab.ui.control.EditField
        UpdateButton                   matlab.ui.control.Button
        ReadyLampLabel                 matlab.ui.control.Label
        Ready                          matlab.ui.control.Lamp
        Inc_freq                       matlab.ui.control.Button
        Dec_freq                       matlab.ui.control.Button
        Inc_beta                       matlab.ui.control.Button
        Dec_beta                       matlab.ui.control.Button
        OptionsPanel                   matlab.ui.container.Panel
        HelpButton                     matlab.ui.control.Button
        SaveExitButton                 matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        ControllerBodePanel            matlab.ui.container.Panel
        Bode_mag                       matlab.ui.control.UIAxes
        Bode_phase                     matlab.ui.control.UIAxes
        swnLabel                       matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        swnbetaLabel                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp;
        G
        des
        freq;
        beta;
        edit_flag; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, G, des, EditMode)
            app.CallingApp = mainapp;
            app.G = G;
            app.des = des;
            app.edit_flag = EditMode;
            app.Freq.Value = num2str(1);
            app.Beta.Value = num2str(10);
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            clag = qctrl.lag(str2double(app.Freq.Value),str2double(app.Beta.Value));
            
            w0 = app.des.nom.frequency;
            c_nicresp = n2c(clag.nicresp(w0));
            mag = mag2db(abs(c_nicresp));
            phase_local = rad2deg(angle(c_nicresp));
            
            semilogx(app.Bode_mag,w0,mag);
            semilogx(app.Bode_phase,w0,phase_local);
            app.freq = str2double(app.Freq.Value);
            app.beta = str2double(app.Beta.Value);
            
            if ~isempty(str2double(app.Freq.Value)) && ~isempty(str2double(app.Beta.Value))
                app.Ready.Color = [0,1,0];
            end

            lag_flag = 1;
            updateLagCont(app.CallingApp,app.G,app.des,app.freq,app.beta,lag_flag,100)
        end

        % Button pushed function: SaveExitButton
        function SaveExitButtonPushed(app, event)
            lag_flag = 1;
            updateLagCont(app.CallingApp,app.G,app.des,app.freq,app.beta,lag_flag,app.edit_flag)
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            lag_flag = 0;
            updateLagCont(app.CallingApp,app.G,app.des,app.freq,app.beta,lag_flag,app.edit_flag)
            delete(app);
        end

        % Button pushed function: Inc_freq
        function Inc_freqButtonPushed(app, event)
            app.freq = str2double(app.Freq.Value);
            app.Freq.Value = num2str(app.freq + 1);
        end

        % Button pushed function: Dec_freq
        function Dec_freqButtonPushed(app, event)
            app.freq = str2double(app.Freq.Value);
            newFreq = app.freq * 0.8;
            app.Freq.Value = num2str(newFreq);
        end

        % Button pushed function: Inc_beta
        function Inc_betaButtonPushed(app, event)
            app.beta = str2double(app.Beta.Value); 
            app.Beta.Value =num2str(app.beta + 10);
        end

        % Button pushed function: Dec_beta
        function Dec_betaButtonPushed(app, event)
            app.beta = str2double(app.Beta.Value); 
            app.Beta.Value =num2str(app.beta - 10);
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            helpGUI('lag')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'Lag Controller';
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.FrequencyradsecEditFieldLabel = uilabel(app.ParametersPanel);
            app.FrequencyradsecEditFieldLabel.Text = 'Frequency [rad/sec]';
            app.Freq = uieditfield(app.ParametersPanel, 'text');
            app.BetaEditFieldLabel = uilabel(app.ParametersPanel);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.Beta = uieditfield(app.ParametersPanel, 'text');
            app.UpdateButton = uibutton(app.ParametersPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.ReadyLampLabel = uilabel(app.ParametersPanel);
            app.ReadyLampLabel.Text = 'Ready';
            app.Ready = uilamp(app.ParametersPanel);
            app.Inc_freq = uibutton(app.ParametersPanel, 'push');
            app.Inc_freq.ButtonPushedFcn = createCallbackFcn(app, @Inc_freqButtonPushed, true);
            app.Inc_freq.Text = '+';
            app.Dec_freq = uibutton(app.ParametersPanel, 'push');
            app.Dec_freq.ButtonPushedFcn = createCallbackFcn(app, @Dec_freqButtonPushed, true);
            app.Dec_freq.Text = '-';
            app.Inc_beta = uibutton(app.ParametersPanel, 'push');
            app.Inc_beta.ButtonPushedFcn = createCallbackFcn(app, @Inc_betaButtonPushed, true);
            app.Inc_beta.Text = '+';
            app.Dec_beta = uibutton(app.ParametersPanel, 'push');
            app.Dec_beta.ButtonPushedFcn = createCallbackFcn(app, @Dec_betaButtonPushed, true);
            app.Dec_beta.Text = '-';
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.HelpButton = uibutton(app.OptionsPanel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.SaveExitButton = uibutton(app.OptionsPanel, 'push');
            app.SaveExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveExitButtonPushed, true);
            app.SaveExitButton.Text = 'Save & Exit';
            app.CancelButton = uibutton(app.OptionsPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ControllerBodePanel = uipanel(app.UIFigure);
            app.ControllerBodePanel.Title = 'Controller Bode';
            app.Bode_mag = uiaxes(app.ControllerBodePanel);
            title(app.Bode_mag, 'Mag')
            xlabel(app.Bode_mag, 'Frequency [rad/sec]')
            ylabel(app.Bode_mag, 'Mag')
            app.Bode_phase = uiaxes(app.ControllerBodePanel);
            title(app.Bode_phase, 'Phase')
            xlabel(app.Bode_phase, 'Frequency [rad/sec]')
            ylabel(app.Bode_phase, 'Phase')
            app.swnLabel = uilabel(app.UIFigure);
            app.swnLabel.Text = '10*s+wn';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '---------------';
            app.swnbetaLabel = uilabel(app.UIFigure);
            app.swnbetaLabel.Text = '10*s+wn/beta';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lagGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end