classdef pzGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AddPolesandZerosManuallyLabel  matlab.ui.control.Label
        PureIntegratorDerivatorPanel   matlab.ui.container.Panel
        Add                            matlab.ui.control.Button
        SwitchLabel                    matlab.ui.control.Label
        Switch                         matlab.ui.control.Switch
        RealPolePanel                  matlab.ui.container.Panel
        sa1Label                       matlab.ui.control.Label
        Add1                           matlab.ui.control.Button
        aSliderLabel                   matlab.ui.control.Label
        a1                             matlab.ui.control.Slider
        inc_1                          matlab.ui.control.Button
        dec_1                          matlab.ui.control.Button
        aEditFieldLabel_3              matlab.ui.control.Label
        aEditField                     matlab.ui.control.EditField
        FirstOrderTransferFunctionPanel  matlab.ui.container.Panel
        csdasbLabel                    matlab.ui.control.Label
        aEditFieldLabel_2              matlab.ui.control.Label
        a5                             matlab.ui.control.EditField
        bEditFieldLabel_2              matlab.ui.control.Label
        b                              matlab.ui.control.EditField
        Add5                           matlab.ui.control.Button
        cLabel                         matlab.ui.control.Label
        c                              matlab.ui.control.EditField
        dLabel                         matlab.ui.control.Label
        d                              matlab.ui.control.EditField
        ComplexPolePanel               matlab.ui.container.Panel
        s2w22zetasw1Label              matlab.ui.control.Label
        Add2                           matlab.ui.control.Button
        wLabel                         matlab.ui.control.Label
        w2                             matlab.ui.control.Slider
        inc_2                          matlab.ui.control.Button
        dec_2                          matlab.ui.control.Button
        wEditFieldLabel                matlab.ui.control.Label
        w_2                            matlab.ui.control.EditField
        inc_5                          matlab.ui.control.Button
        zeta2                          matlab.ui.control.Slider
        dec_5                          matlab.ui.control.Button
        zetaLabel                      matlab.ui.control.Label
        zetaEditFieldLabel             matlab.ui.control.Label
        zeta_2                         matlab.ui.control.EditField
        RealZeroPanel                  matlab.ui.container.Panel
        sa1Label_2                     matlab.ui.control.Label
        Add4                           matlab.ui.control.Button
        aSliderLabel_4                 matlab.ui.control.Label
        a4                             matlab.ui.control.Slider
        inc_4                          matlab.ui.control.Button
        dec_4                          matlab.ui.control.Button
        aEditField_4Label              matlab.ui.control.Label
        aEditField_4                   matlab.ui.control.EditField
        ComplexZeroPanel               matlab.ui.container.Panel
        s2w22zetasw1Label_2            matlab.ui.control.Label
        Add3                           matlab.ui.control.Button
        wLabel_2                       matlab.ui.control.Label
        w3                             matlab.ui.control.Slider
        inc_3                          matlab.ui.control.Button
        dec_3                          matlab.ui.control.Button
        wEditField_2Label              matlab.ui.control.Label
        w_3                            matlab.ui.control.EditField
        zetaLabel_2                    matlab.ui.control.Label
        inc_6                          matlab.ui.control.Button
        zeta3                          matlab.ui.control.Slider
        dec_6                          matlab.ui.control.Button
        zetaEditField_2Label           matlab.ui.control.Label
        zeta_3                         matlab.ui.control.EditField
        OptionsPanel                   matlab.ui.container.Panel
        HelpButton                     matlab.ui.control.Button
        ExitButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp;
        G
        des
        edit_flag;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, G, des, EditMode)
            app.CallingApp = mainapp;
            app.G = G;
            app.des = des;
            app.edit_flag = EditMode;
        end

        % Button pushed function: Add
        function AddButtonPushed(app, event)
            pz_flag = 1;
            CorD = app.Switch.Value;
            if strcmp(CorD,'Integrator')
                flag =0;
                a=0;
                b=0;
                c=0;
                d=0;
            else
                flag =1;
                a=0;
                b=0;
                c=0;
                d=0;
            end
            updatePZCont(app.CallingApp,app.G,app.des,a,b,c,d,flag,pz_flag,app.edit_flag)
        end

        % Button pushed function: Add1
        function Add1ButtonPushed(app, event)
            pz_flag = 1;
            flag = 2;
            a = app.a1.Value;
            fprintf('%d',a)
            b=0;
            c=0;
            d=0;
            updatePZCont(app.CallingApp,app.G,app.des,a,b,c,d,flag,pz_flag,app.edit_flag)
        end

        % Button pushed function: Add2
        function Add2ButtonPushed(app, event)
            pz_flag = 1;
            flag = 3;
            a = app.w2.Value;
            b = app.zeta2.Value;
            c=0;
            d=0;
            updatePZCont(app.CallingApp,app.G,app.des,a,b,c,d,flag,pz_flag,app.edit_flag)
        end

        % Button pushed function: Add3
        function Add3ButtonPushed(app, event)
            pz_flag = 1;
            flag = 4;
            a = app.w3.Value;
            b = app.zeta3.Value;
            c=0;
            d=0;
            updatePZCont(app.CallingApp,app.G,app.des,a,b,c,d,flag,pz_flag,app.edit_flag)
        end

        % Button pushed function: Add4
        function Add4ButtonPushed(app, event)
            pz_flag = 1;
            flag = 5;
            a = app.a4.Value;
            b=0;
            c=0;
            d=0;
            updatePZCont(app.CallingApp,app.G,app.des,a,b,c,d,flag,pz_flag,app.edit_flag)
        end

        % Button pushed function: Add5
        function Add5ButtonPushed(app, event)
            pz_flag = 1;
            flag = 6;
            a = str2double(app.a5.Value);
            b=str2double(app.b.Value);
            c=str2double(app.c.Value);
            d=str2double(app.d.Value);
            updatePZCont(app.CallingApp,app.G,app.des,a,b,c,d,flag,pz_flag,app.edit_flag)
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app)
        end

        % Callback function
        function DoneButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: inc_1
        function inc_1ButtonPushed(app, event)
            Value = app.a1.Value;
            if abs(Value) > 9
                app.aEditField.Value = num2str(Value+10);
                app.a1.Value = Value + 10;
                app.a1.Limits(2) = app.a1.Limits(2) + 10;
                app.a1.Limits(1) = app.a1.Limits(1) + 10;
            else
                app.aEditField.Value = num2str(Value+1);
                app.a1.Value = Value + 1;
                app.a1.Limits(2) = app.a1.Limits(2) + 1;
                app.a1.Limits(1) = app.a1.Limits(1) + 1;
            end
        end

        % Button pushed function: inc_2
        function inc_2ButtonPushed(app, event)
            Value = app.w2.Value;
            if abs(Value) > 9
                app.w_2.Value = num2str(Value+10);
                app.w2.Value = Value + 10;
                app.w2.Limits(2) = app.w2.Limits(2) + 10;
                app.w2.Limits(1) = app.w2.Limits(1) + 10;
            else
                app.w_2.Value = num2str(Value+1);
                app.w2.Value = Value + 1;
                app.w2.Limits(2) = app.w2.Limits(2) + 1;
                app.w2.Limits(1) = app.w2.Limits(1) + 1;
            end
        end

        % Button pushed function: inc_3
        function inc_3ButtonPushed(app, event)
            Value = app.w3.Value;
            if abs(Value) > 9
                app.w_3.Value = num2str(Value+10);
                app.w3.Value = Value + 10;
                app.w3.Limits(2) = app.w3.Limits(2) + 10;
                app.w3.Limits(1) = app.w3.Limits(1) + 10;
            else
                app.w_3.Value = num2str(Value+1);
                app.w3.Value = Value + 1;
                app.w3.Limits(2) = app.w3.Limits(2) + 1;
                app.w3.Limits(1) = app.w3.Limits(1) + 1;
            end
        end

        % Button pushed function: inc_4
        function inc_4ButtonPushed(app, event)
            Value = app.a4.Value;
            if abs(Value) > 9
                app.aEditField_4.Value = num2str(Value+10);
                app.a4.Value = Value + 10;
                app.a4.Limits(2) = app.a4.Limits(2) + 10;
                app.a4.Limits(1) = app.a4.Limits(1) + 10;
            else
                app.aEditField_4.Value = num2str(Value+1);
                app.a4.Value = Value + 1;
                app.a4.Limits(2) = app.a4.Limits(2) + 1;
                app.a4.Limits(1) = app.a4.Limits(1) + 1;
            end
        end

        % Button pushed function: inc_5
        function inc_5ButtonPushed(app, event)
            Value = app.zeta2.Value;
            if abs(Value) > 9
                app.zeta_2.Value = num2str(Value+10);
                app.zeta2.Value = Value + 10;
                app.zeta2.Limits(2) = app.zeta2.Limits(2) + 10;
                app.zeta2.Limits(1) = app.zeta2.Limits(1) + 10;
            else
                app.zeta_2.Value = num2str(Value+1);
                app.zeta2.Value = Value + 1;
                app.zeta2.Limits(2) = app.zeta2.Limits(2) + 1;
                app.zeta2.Limits(1) = app.zeta2.Limits(1) + 1;
            end
        end

        % Button pushed function: inc_6
        function inc_6ButtonPushed(app, event)
            Value = app.zeta3.Value;
            if abs(Value) > 9
                app.zeta_3.Value = num2str(Value+10);
                app.zeta3.Value = Value + 10;
                app.zeta3.Limits(2) = app.zeta3.Limits(2) + 10;
                app.zeta3.Limits(1) = app.zeta3.Limits(1) + 10;
            else
                app.zeta_3.Value = num2str(Value+1);
                app.zeta3.Value = Value + 1;
                app.zeta3.Limits(2) = app.zeta3.Limits(2) + 1;
                app.zeta3.Limits(1) = app.zeta3.Limits(1) + 1;
            end           
        end

        % Button pushed function: dec_1
        function dec_1ButtonPushed(app, event)
            Value = app.a1.Value;
            if abs(Value) > 9
                app.aEditField.Value = num2str(Value-10);
                app.a1.Value = Value - 10;
                app.a1.Limits(2) = app.a1.Limits(2) - 10;
                app.a1.Limits(1) = app.a1.Limits(1) - 10;
            else
                app.aEditField.Value = num2str(Value-1);
                app.a1.Value = Value - 1;
                app.a1.Limits(2) = app.a1.Limits(2) - 1;
                app.a1.Limits(1) = app.a1.Limits(1) - 1;
            end
        end

        % Button pushed function: dec_2
        function dec_2ButtonPushed(app, event)
            Value = app.w2.Value;
            if abs(Value) > 9
                app.w_2.Value = num2str(Value-10);
                app.w2.Value = Value - 10;
                app.w2.Limits(2) = app.w2.Limits(2) - 10;
                app.w2.Limits(1) = app.w2.Limits(1) - 10;
            else
                app.w_2.Value = num2str(Value-1);
                app.w2.Value = Value - 1;
                app.w2.Limits(2) = app.w2.Limits(2) - 1;
                app.w2.Limits(1) = app.w2.Limits(1) - 1;
            end
        end

        % Button pushed function: dec_3
        function dec_3ButtonPushed(app, event)
            Value = app.w3.Value;
            if abs(Value) > 9
                app.w_3.Value = num2str(Value-10);
                app.w3.Value = Value - 10;
                app.w3.Limits(2) = app.w3.Limits(2) - 10;
                app.w3.Limits(1) = app.w3.Limits(1) - 10;
            else
                app.w_3.Value = num2str(Value-1);
                app.w3.Value = Value - 1;
                app.w3.Limits(2) = app.w3.Limits(2) - 1;
                app.w3.Limits(1) = app.w3.Limits(1) - 1;
            end
        end

        % Button pushed function: dec_4
        function dec_4ButtonPushed(app, event)
            Value = app.a4.Value;
            if abs(Value) > 9
                app.aEditField_4.Value = num2str(Value-10);
                app.a4.Value = Value - 10;
                app.a4.Limits(2) = app.a4.Limits(2) - 10;
                app.a4.Limits(1) = app.a4.Limits(1) - 10;
            else
                app.aEditField_4.Value = num2str(Value-1);
                app.a4.Value = Value - 1;
                app.a4.Limits(2) = app.a4.Limits(2) - 1;
                app.a4.Limits(1) = app.a4.Limits(1) - 1;
            end
        end

        % Button pushed function: dec_5
        function dec_5ButtonPushed(app, event)
            Value = app.zeta2.Value;
            if abs(Value) > 9
                app.zeta_2.Value = num2str(Value-10);
                app.zeta2.Value = Value - 10;
                app.zeta2.Limits(2) = app.zeta2.Limits(2) - 10;
                app.zeta2.Limits(1) = app.zeta2.Limits(1) - 10;
            else
                app.zeta_2.Value = num2str(Value-1);
                app.zeta2.Value = Value - 1;
                app.zeta2.Limits(2) = app.zeta2.Limits(2) - 1;
                app.zeta2.Limits(1) = app.zeta2.Limits(1) - 1;
            end           
        end

        % Button pushed function: dec_6
        function dec_6ButtonPushed(app, event)
            Value = app.zeta3.Value;
            if abs(Value) > 9
                app.zeta_3.Value = num2str(Value-10);
                app.zeta3.Value = Value - 10;
                app.zeta3.Limits(2) = app.zeta3.Limits(2) - 10;
                app.zeta3.Limits(1) = app.zeta3.Limits(1) - 10;
            else
                app.zeta_3.Value = num2str(Value-1);
                app.zeta3.Value = Value - 1;
                app.zeta3.Limits(2) = app.zeta3.Limits(2) - 1;
                app.zeta3.Limits(1) = app.zeta3.Limits(1) - 1;
            end
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            helpGUI('pz')
        end

        % Value changed function: aEditField
        function aEditFieldValueChanged(app, event)
            value = str2double(app.aEditField.Value);
            app.a1.Limits = [value-10 value+10];
            app.a1.Value = value;
        end

        % Value changed function: w_2
        function w_2ValueChanged(app, event)
            value = str2double(app.w_2.Value);
            app.w2.Limits = [value-10 value+10];
            app.w2.Value = value;
        end

        % Value changed function: w_3
        function w_3ValueChanged(app, event)
            value = str2double(app.w_3.Value);
            app.w3.Limits = [value-10 value+10];
            app.w3.Value = value;
        end

        % Value changed function: aEditField_4
        function aEditField_4ValueChanged(app, event)
            value = str2double(app.aEditField_4.Value);
            app.a4.Limits = [value-10 value+10];
            app.a4.Value = value;
        end

        % Value changed function: zeta_2
        function zeta_2ValueChanged(app, event)
            value = str2double(app.zeta_2.Value);
            app.zeta2.Limits = [value-10 value+10];
            app.zeta2.Value = value;
        end

        % Value changed function: zeta_3
        function zeta_3ValueChanged(app, event)
            value = str2double(app.zeta_3.Value);
            app.zeta3.Limits = [value-10 value+10];
            app.zeta3.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.AddPolesandZerosManuallyLabel = uilabel(app.UIFigure);
            app.AddPolesandZerosManuallyLabel.Text = 'Add Poles and Zeros Manually';
            app.PureIntegratorDerivatorPanel = uipanel(app.UIFigure);
            app.PureIntegratorDerivatorPanel.Title = 'Pure Integrator/Derivator';
            app.Add = uibutton(app.PureIntegratorDerivatorPanel, 'push');
            app.Add.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.Add.Text = 'Add';
            app.SwitchLabel = uilabel(app.PureIntegratorDerivatorPanel);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.PureIntegratorDerivatorPanel, 'slider');
            app.Switch.Items = {'Integrator', 'Derivator'};
            app.Switch.Value = 'Integrator';
            app.RealPolePanel = uipanel(app.UIFigure);
            app.RealPolePanel.Title = 'Real Pole';
            app.sa1Label = uilabel(app.RealPolePanel);
            app.sa1Label.Text = '1/(s/a+1)';
            app.Add1 = uibutton(app.RealPolePanel, 'push');
            app.Add1.ButtonPushedFcn = createCallbackFcn(app, @Add1ButtonPushed, true);
            app.Add1.Text = 'Add';
            app.aSliderLabel = uilabel(app.RealPolePanel);
            app.aSliderLabel.Text = 'a';
            app.a1 = uislider(app.RealPolePanel);
            app.a1.Limits = [-10 10];
            app.inc_1 = uibutton(app.RealPolePanel, 'push');
            app.inc_1.ButtonPushedFcn = createCallbackFcn(app, @inc_1ButtonPushed, true);
            app.inc_1.Text = '+';
            app.dec_1 = uibutton(app.RealPolePanel, 'push');
            app.dec_1.ButtonPushedFcn = createCallbackFcn(app, @dec_1ButtonPushed, true);
            app.dec_1.Text = '-';
            app.aEditFieldLabel_3 = uilabel(app.RealPolePanel);
            app.aEditFieldLabel_3.Text = 'a';
            app.aEditField = uieditfield(app.RealPolePanel, 'text');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged, true);
            app.aEditField.Value = '0';
            app.FirstOrderTransferFunctionPanel = uipanel(app.UIFigure);
            app.FirstOrderTransferFunctionPanel.Title = 'First Order Transfer Function';
            app.csdasbLabel = uilabel(app.FirstOrderTransferFunctionPanel);
            app.csdasbLabel.Text = '(c*s+d)/(a*s+b)';
            app.aEditFieldLabel_2 = uilabel(app.FirstOrderTransferFunctionPanel);
            app.aEditFieldLabel_2.Text = 'a';
            app.a5 = uieditfield(app.FirstOrderTransferFunctionPanel, 'text');
            app.a5.Value = '1';
            app.bEditFieldLabel_2 = uilabel(app.FirstOrderTransferFunctionPanel);
            app.bEditFieldLabel_2.Text = 'b';
            app.b = uieditfield(app.FirstOrderTransferFunctionPanel, 'text');
            app.b.Value = '0';
            app.Add5 = uibutton(app.FirstOrderTransferFunctionPanel, 'push');
            app.Add5.ButtonPushedFcn = createCallbackFcn(app, @Add5ButtonPushed, true);
            app.Add5.Text = 'Add';
            app.cLabel = uilabel(app.FirstOrderTransferFunctionPanel);
            app.cLabel.Text = 'c';
            app.c = uieditfield(app.FirstOrderTransferFunctionPanel, 'text');
            app.c.Value = '0';
            app.dLabel = uilabel(app.FirstOrderTransferFunctionPanel);
            app.dLabel.Text = 'd';
            app.d = uieditfield(app.FirstOrderTransferFunctionPanel, 'text');
            app.d.Value = '0';
            app.ComplexPolePanel = uipanel(app.UIFigure);
            app.ComplexPolePanel.Title = 'Complex Pole';
            app.s2w22zetasw1Label = uilabel(app.ComplexPolePanel);
            app.s2w22zetasw1Label.Text = '1/(s^2/w^2+2*zeta*s/w+1)';
            app.Add2 = uibutton(app.ComplexPolePanel, 'push');
            app.Add2.ButtonPushedFcn = createCallbackFcn(app, @Add2ButtonPushed, true);
            app.Add2.Text = 'Add';
            app.wLabel = uilabel(app.ComplexPolePanel);
            app.wLabel.Text = 'w';
            app.w2 = uislider(app.ComplexPolePanel);
            app.w2.Limits = [-10 10];
            app.inc_2 = uibutton(app.ComplexPolePanel, 'push');
            app.inc_2.ButtonPushedFcn = createCallbackFcn(app, @inc_2ButtonPushed, true);
            app.inc_2.Text = '+';
            app.dec_2 = uibutton(app.ComplexPolePanel, 'push');
            app.dec_2.ButtonPushedFcn = createCallbackFcn(app, @dec_2ButtonPushed, true);
            app.dec_2.Text = '-';
            app.wEditFieldLabel = uilabel(app.ComplexPolePanel);
            app.wEditFieldLabel.Text = 'w';
            app.w_2 = uieditfield(app.ComplexPolePanel, 'text');
            app.w_2.ValueChangedFcn = createCallbackFcn(app, @w_2ValueChanged, true);
            app.w_2.Value = '0';
            app.inc_5 = uibutton(app.ComplexPolePanel, 'push');
            app.inc_5.ButtonPushedFcn = createCallbackFcn(app, @inc_5ButtonPushed, true);
            app.inc_5.Text = '+';
            app.zeta2 = uislider(app.ComplexPolePanel);
            app.zeta2.Limits = [-10 10];
            app.dec_5 = uibutton(app.ComplexPolePanel, 'push');
            app.dec_5.ButtonPushedFcn = createCallbackFcn(app, @dec_5ButtonPushed, true);
            app.dec_5.Text = '-';
            app.zetaLabel = uilabel(app.ComplexPolePanel);
            app.zetaLabel.Text = 'zeta';
            app.zetaEditFieldLabel = uilabel(app.ComplexPolePanel);
            app.zetaEditFieldLabel.Text = 'zeta';
            app.zeta_2 = uieditfield(app.ComplexPolePanel, 'text');
            app.zeta_2.ValueChangedFcn = createCallbackFcn(app, @zeta_2ValueChanged, true);
            app.zeta_2.Value = '0';
            app.RealZeroPanel = uipanel(app.UIFigure);
            app.RealZeroPanel.Title = 'Real Zero';
            app.sa1Label_2 = uilabel(app.RealZeroPanel);
            app.sa1Label_2.Text = 's/a+1';
            app.Add4 = uibutton(app.RealZeroPanel, 'push');
            app.Add4.ButtonPushedFcn = createCallbackFcn(app, @Add4ButtonPushed, true);
            app.Add4.Text = 'Add';
            app.aSliderLabel_4 = uilabel(app.RealZeroPanel);
            app.aSliderLabel_4.Text = 'a';
            app.a4 = uislider(app.RealZeroPanel);
            app.a4.Limits = [-10 10];
            app.inc_4 = uibutton(app.RealZeroPanel, 'push');
            app.inc_4.ButtonPushedFcn = createCallbackFcn(app, @inc_4ButtonPushed, true);
            app.inc_4.Text = '+';
            app.dec_4 = uibutton(app.RealZeroPanel, 'push');
            app.dec_4.ButtonPushedFcn = createCallbackFcn(app, @dec_4ButtonPushed, true);
            app.dec_4.Text = '-';
            app.aEditField_4Label = uilabel(app.RealZeroPanel);
            app.aEditField_4Label.Text = 'a';
            app.aEditField_4 = uieditfield(app.RealZeroPanel, 'text');
            app.aEditField_4.ValueChangedFcn = createCallbackFcn(app, @aEditField_4ValueChanged, true);
            app.aEditField_4.Value = '0';
            app.ComplexZeroPanel = uipanel(app.UIFigure);
            app.ComplexZeroPanel.Title = 'Complex Zero';
            app.s2w22zetasw1Label_2 = uilabel(app.ComplexZeroPanel);
            app.s2w22zetasw1Label_2.Text = '(s^2/w^2+2*zeta*s/w+1)';
            app.Add3 = uibutton(app.ComplexZeroPanel, 'push');
            app.Add3.ButtonPushedFcn = createCallbackFcn(app, @Add3ButtonPushed, true);
            app.Add3.Text = 'Add';
            app.wLabel_2 = uilabel(app.ComplexZeroPanel);
            app.wLabel_2.Text = 'w';
            app.w3 = uislider(app.ComplexZeroPanel);
            app.w3.Limits = [-10 10];
            app.inc_3 = uibutton(app.ComplexZeroPanel, 'push');
            app.inc_3.ButtonPushedFcn = createCallbackFcn(app, @inc_3ButtonPushed, true);
            app.inc_3.Text = '+';
            app.dec_3 = uibutton(app.ComplexZeroPanel, 'push');
            app.dec_3.ButtonPushedFcn = createCallbackFcn(app, @dec_3ButtonPushed, true);
            app.dec_3.Text = '-';
            app.wEditField_2Label = uilabel(app.ComplexZeroPanel);
            app.wEditField_2Label.Text = 'w';
            app.w_3 = uieditfield(app.ComplexZeroPanel, 'text');
            app.w_3.ValueChangedFcn = createCallbackFcn(app, @w_3ValueChanged, true);
            app.w_3.Value = '0';
            app.zetaLabel_2 = uilabel(app.ComplexZeroPanel);
            app.zetaLabel_2.Text = 'zeta';
            app.inc_6 = uibutton(app.ComplexZeroPanel, 'push');
            app.inc_6.ButtonPushedFcn = createCallbackFcn(app, @inc_6ButtonPushed, true);
            app.inc_6.Text = '+';
            app.zeta3 = uislider(app.ComplexZeroPanel);
            app.zeta3.Limits = [-10 10];
            app.dec_6 = uibutton(app.ComplexZeroPanel, 'push');
            app.dec_6.ButtonPushedFcn = createCallbackFcn(app, @dec_6ButtonPushed, true);
            app.dec_6.Text = '-';
            app.zetaEditField_2Label = uilabel(app.ComplexZeroPanel);
            app.zetaEditField_2Label.Text = 'zeta';
            app.zeta_3 = uieditfield(app.ComplexZeroPanel, 'text');
            app.zeta_3.ValueChangedFcn = createCallbackFcn(app, @zeta_3ValueChanged, true);
            app.zeta_3.Value = '0';
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.HelpButton = uibutton(app.OptionsPanel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.ExitButton = uibutton(app.OptionsPanel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pzGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end