classdef sample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        PlotButton                 matlab.ui.control.Button
        NumeratorEditFieldLabel    matlab.ui.control.Label
        NumeratorEditField         matlab.ui.control.EditField
        DenominatorEditFieldLabel  matlab.ui.control.Label
        DenominatorEditField       matlab.ui.control.EditField
    end

    
    properties (Access = private)
        ax_step % Description
        position = [100 230 400 200]
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ax_step = axes(app.UIFigure, "Units", "pixels", ...
                "Position", app.position);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
%             delete(app.ax_step);
            num = str2num(app.NumeratorEditField.Value);
            den = str2num(app.DenominatorEditField.Value);
            sys = tf(num,den);
            step(app.ax_step, sys);
%             pzplot(app.ax_step, sys);
%             lsim(app.ax_step, sys, ones(100,1), linspace(0,20,100)');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.NumeratorEditFieldLabel = uilabel(app.UIFigure);
            app.NumeratorEditFieldLabel.Text = 'Numerator';
            app.NumeratorEditField = uieditfield(app.UIFigure, 'text');
            app.NumeratorEditField.Value = '[1]';
            app.DenominatorEditFieldLabel = uilabel(app.UIFigure);
            app.DenominatorEditFieldLabel.Text = 'Denominator';
            app.DenominatorEditField = uieditfield(app.UIFigure, 'text');
            app.DenominatorEditField.Value = '[1 2 3]';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end